classdef appICHdetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure  matlab.ui.Figure
        DiagnosisTextArea               matlab.ui.control.TextArea
        DiagnosisTextAreaLabel          matlab.ui.control.Label
        ClassifyImageButton             matlab.ui.control.Button
        LoadCTimageButton               matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PatientdataTab                  matlab.ui.container.Tab
        EmergencycontactnameEditField   matlab.ui.control.EditField
        EmergencycontactnameEditFieldLabel  matlab.ui.control.Label
        SmokingStatusDropDown           matlab.ui.control.DropDown
        SmokingStatusDropDownLabel      matlab.ui.control.Label
        PhonenumberEditField_2          matlab.ui.control.NumericEditField
        PhonenumberEditField_2Label     matlab.ui.control.Label
        EmergencycontactphonenumberEditField  matlab.ui.control.NumericEditField
        EmergencycontactphonenumberEditFieldLabel  matlab.ui.control.Label
        RaceandEthnicityDropDown        matlab.ui.control.DropDown
        RaceandEthnicityDropDownLabel   matlab.ui.control.Label
        DateofBirthDatePicker           matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel      matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        VitalSignsTab                   matlab.ui.container.Tab
        PulseoximetryEditField          matlab.ui.control.NumericEditField
        PulseoximetryEditFieldLabel     matlab.ui.control.Label
        BodytemperatureEditField        matlab.ui.control.NumericEditField
        BodytemperatureEditFieldLabel   matlab.ui.control.Label
        RespiratoryrateEditField        matlab.ui.control.NumericEditField
        RespiratoryrateEditFieldLabel   matlab.ui.control.Label
        HeartrateEditField              matlab.ui.control.NumericEditField
        HeartrateEditFieldLabel         matlab.ui.control.Label
        BodyweightEditField             matlab.ui.control.NumericEditField
        BodyweightEditFieldLabel        matlab.ui.control.Label
        BodyheightEditField             matlab.ui.control.NumericEditField
        BodyheightEditFieldLabel        matlab.ui.control.Label
        SystolicbloodpressureEditField  matlab.ui.control.NumericEditField
        SystolicbloodpressureEditFieldLabel  matlab.ui.control.Label
        DiastolicbloodpressureEditField  matlab.ui.control.NumericEditField
        DiastolicbloodpressureEditFieldLabel  matlab.ui.control.Label
        MedicationsTab                  matlab.ui.container.Tab
        MedicationAllergiesEditField    matlab.ui.control.EditField
        MedicationAllergiesEditFieldLabel  matlab.ui.control.Label
        MedicationsEditField            matlab.ui.control.EditField
        MedicationsEditFieldLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            Data.Name = app.NameEditField.Value;
            Data.Sex = app.SexDropDown.Value;
            Data.DateOfBirth = app.DateofBirthDatePicker.Value;
            Data.Race = app.RaceandEthnicityDropDown.Value;
            Data.Phone = app.PhonenumberEditField_2.Value;
            Data.SmokingStatus = app.SmokingStatusDropDown.Value;
            Data.Emergencycontactname = app.EmergencycontactnameEditField.Value;
            Data.Emergencycontactphonenumber = app.EmergencycontactphonenumberEditField.Value;
            Data.Diagnosis = app.DiagnosisTextArea.Value;
            Data.Pulseoximetry = app.PulseoximetryEditField.Value;
            Data.Bodytemperature = app.BodytemperatureEditField.Value;
            Data.Respiratoryrate = app.RespiratoryrateEditField.Value;
            Data.Heartrate = app.HeartrateEditField.Value;
            Data.Bodyweight = app.BodyweightEditField.Value;
            Data.Systolicbloodpressure = app.SystolicbloodpressureEditField.Value;
            Data.Diastolicbloodpressure = app.DiastolicbloodpressureEditField.Value;
            Data.MedicationAllergies = app.MedicationAllergiesEditField.Value;
            Data.Medications = app.MedicationsEditField.Value;

            % Converting structure array to table
            Data = struct2table(Data,"AsArray",true);

            %saving the file with the provided name in the indicated path
            [file,path] = uiputfile('*.mat','Workspace File');
            if path
                cd(path);
                save(file,'Data');
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            fig = uifigure('HandleVisibility', 'on');%('Position',[100 100 425 275]);
            msg = 'Would you like to save the data?';
            title = 'Confirm Exit';
            selection = uiconfirm(fig,msg,title,...
                'Options',{'Yes','No'},'Icon','warning');
            switch  selection
                case 'Yes'
                    SaveButtonPushed(app, event)
                case 'No'
                    % do nothing
            end
            close(fig);
            close(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure);
            close all;
        end

        % Button pushed function: LoadCTimageButton
        function LoadCTimageButtonPushed(app, event)
            global Image;
            [filename,filepath]=uigetfile({'*.png';'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file to open');
            if filepath == 0
                return;
            end
            fullpathname = strcat(filepath,filename);
            Image = imread(fullpathname);
            imshow(Image,'parent', app.UIAxes);
        end

        % Button pushed function: ClassifyImageButton
        function ClassifyImageButtonPushed(app, event)
            load('TrainedNetwork.mat');
            global Image;

            if isempty(Image)
                fig = uifigure;
                uialert(fig,'Please, load a CT image file first','Invalid File');
                return;
            end
            % Resizing image to 224-by-224-by-3 pixels to make them
            % compatible with the input size of the network.
            inputSize = net.Layers(1).InputSize;
            img = imresize(Image,inputSize(1:2));

            [YPred,scores] = classify(net,img); % Classifying image using last trained network

            if YPred == 'healthy'
                answer = "Healthy (score = " + num2str(100*max(scores),3) + "%)";
            else
                answer = "Intracranial hemorrhage (score = " + num2str(100*max(scores),3) + "%)";
            end

            % Displaying diagnosis in the Text Area
            app.DiagnosisTextArea.Value = answer;

            % Grad-CAM reveals the Resnet decisions
            scoreMap = gradCAM(net,img,YPred);

            % Plotting Grad-CAM in a new figure
            figure;
            imshow(img);
            hold on;
            imagesc(scoreMap,'AlphaData',0.5);
            colormap jet
            hold off;
            title("Grad-CAM");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure = uifigure('Visible', 'off');
            app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure.Name = 'Intracerebral Hemorrhage Detection on CT Images Using ResNet-18';
            app.UIAxes = uiaxes(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure);
            app.PatientdataTab = uitab(app.TabGroup);
            app.PatientdataTab.Title = 'Patient data';
            app.NameEditFieldLabel = uilabel(app.PatientdataTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PatientdataTab, 'text');
            app.SexDropDownLabel = uilabel(app.PatientdataTab);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.PatientdataTab);
            app.SexDropDown.Items = {'Male', 'Female', 'Non-binary', 'Transgender', 'Prefer not to say'};
            app.SexDropDown.Value = 'Male';
            app.DateofBirthDatePickerLabel = uilabel(app.PatientdataTab);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirthDatePicker = uidatepicker(app.PatientdataTab);
            app.RaceandEthnicityDropDownLabel = uilabel(app.PatientdataTab);
            app.RaceandEthnicityDropDownLabel.Text = 'Race and Ethnicity';
            app.RaceandEthnicityDropDown = uidropdown(app.PatientdataTab);
            app.RaceandEthnicityDropDown.Items = {'White', 'Hispanic or Latino', 'Black or African American', 'American Indian or Alaska Native', 'Asian', 'Native Hawaiian or Other Pacific Islander'};
            app.RaceandEthnicityDropDown.Value = 'White';
            app.EmergencycontactphonenumberEditFieldLabel = uilabel(app.PatientdataTab);
            app.EmergencycontactphonenumberEditFieldLabel.Text = 'Emergency contact phone number';
            app.EmergencycontactphonenumberEditField = uieditfield(app.PatientdataTab, 'numeric');
            app.EmergencycontactphonenumberEditField.ValueDisplayFormat = '%.0f';
            app.PhonenumberEditField_2Label = uilabel(app.PatientdataTab);
            app.PhonenumberEditField_2Label.Text = 'Phone number';
            app.PhonenumberEditField_2 = uieditfield(app.PatientdataTab, 'numeric');
            app.PhonenumberEditField_2.ValueDisplayFormat = '%.0f';
            app.SmokingStatusDropDownLabel = uilabel(app.PatientdataTab);
            app.SmokingStatusDropDownLabel.Text = 'Smoking Status';
            app.SmokingStatusDropDown = uidropdown(app.PatientdataTab);
            app.SmokingStatusDropDown.Items = {'Current every day smoker', 'Current some day smoker', 'Former smoker', 'Never smoker'};
            app.SmokingStatusDropDown.Value = 'Current every day smoker';
            app.EmergencycontactnameEditFieldLabel = uilabel(app.PatientdataTab);
            app.EmergencycontactnameEditFieldLabel.Text = 'Emergency contact name';
            app.EmergencycontactnameEditField = uieditfield(app.PatientdataTab, 'text');
            app.VitalSignsTab = uitab(app.TabGroup);
            app.VitalSignsTab.Title = 'Vital Signs';
            app.DiastolicbloodpressureEditFieldLabel = uilabel(app.VitalSignsTab);
            app.DiastolicbloodpressureEditFieldLabel.Text = 'Diastolic blood pressure';
            app.DiastolicbloodpressureEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.DiastolicbloodpressureEditField.ValueDisplayFormat = '%.0f';
            app.SystolicbloodpressureEditFieldLabel = uilabel(app.VitalSignsTab);
            app.SystolicbloodpressureEditFieldLabel.Text = 'Systolic blood pressure';
            app.SystolicbloodpressureEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.SystolicbloodpressureEditField.ValueDisplayFormat = '%.0f';
            app.BodyheightEditFieldLabel = uilabel(app.VitalSignsTab);
            app.BodyheightEditFieldLabel.Text = 'Body height';
            app.BodyheightEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.BodyheightEditField.ValueDisplayFormat = '%.2f';
            app.BodyweightEditFieldLabel = uilabel(app.VitalSignsTab);
            app.BodyweightEditFieldLabel.Text = 'Body weight';
            app.BodyweightEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.BodyweightEditField.ValueDisplayFormat = '%.2f';
            app.HeartrateEditFieldLabel = uilabel(app.VitalSignsTab);
            app.HeartrateEditFieldLabel.Text = 'Heart rate';
            app.HeartrateEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.HeartrateEditField.ValueDisplayFormat = '%.0f';
            app.RespiratoryrateEditFieldLabel = uilabel(app.VitalSignsTab);
            app.RespiratoryrateEditFieldLabel.Text = 'Respiratory rate';
            app.RespiratoryrateEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.RespiratoryrateEditField.ValueDisplayFormat = '%.0f';
            app.BodytemperatureEditFieldLabel = uilabel(app.VitalSignsTab);
            app.BodytemperatureEditFieldLabel.Text = 'Body temperature';
            app.BodytemperatureEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.BodytemperatureEditField.ValueDisplayFormat = '%.1f';
            app.PulseoximetryEditFieldLabel = uilabel(app.VitalSignsTab);
            app.PulseoximetryEditFieldLabel.Text = 'Pulse oximetry';
            app.PulseoximetryEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.PulseoximetryEditField.ValueDisplayFormat = '%.1f';
            app.MedicationsTab = uitab(app.TabGroup);
            app.MedicationsTab.Title = 'Medications';
            app.MedicationsEditFieldLabel = uilabel(app.MedicationsTab);
            app.MedicationsEditFieldLabel.Text = 'Medications';
            app.MedicationsEditField = uieditfield(app.MedicationsTab, 'text');
            app.MedicationAllergiesEditFieldLabel = uilabel(app.MedicationsTab);
            app.MedicationAllergiesEditFieldLabel.Text = {'Medication'; 'Allergies'};
            app.MedicationAllergiesEditField = uieditfield(app.MedicationsTab, 'text');
            app.SaveButton = uibutton(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.LoadCTimageButton = uibutton(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure, 'push');
            app.LoadCTimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCTimageButtonPushed, true);
            app.LoadCTimageButton.Text = 'Load CT image';
            app.ClassifyImageButton = uibutton(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure, 'push');
            app.ClassifyImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyImageButtonPushed, true);
            app.ClassifyImageButton.Text = 'Classify Image';
            app.DiagnosisTextAreaLabel = uilabel(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure);
            app.DiagnosisTextAreaLabel.Text = 'Diagnosis';
            app.DiagnosisTextArea = uitextarea(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure);
            % Show the figure after all components are created
            app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appICHdetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IntracerebralHemorrhageDetectiononCTImagesUsingResNet18UIFigure)
        end
    end
end