classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Switch                          matlab.ui.control.Switch
        ClassifyImageButton             matlab.ui.control.Button
        LoadCTimageButton               matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PatientdataTab                  matlab.ui.container.Tab
        SmokingStatusDropDown           matlab.ui.control.DropDown
        SmokingStatusDropDownLabel      matlab.ui.control.Label
        PhonenumberEditField_2          matlab.ui.control.NumericEditField
        PhonenumberEditField_2Label     matlab.ui.control.Label
        EmergencycontactnameEditField   matlab.ui.control.EditField
        EmergencycontactnameEditFieldLabel  matlab.ui.control.Label
        EmergencycontactphonenumberEditField  matlab.ui.control.NumericEditField
        EmergencycontactphonenumberEditFieldLabel  matlab.ui.control.Label
        RaceandEthnicityDropDown        matlab.ui.control.DropDown
        RaceandEthnicityDropDownLabel   matlab.ui.control.Label
        DateofBirthDatePicker           matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel      matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        VitalSignsTab                   matlab.ui.container.Tab
        PulseoximetryEditField          matlab.ui.control.NumericEditField
        PulseoximetryEditFieldLabel     matlab.ui.control.Label
        BodytemperatureEditField        matlab.ui.control.NumericEditField
        BodytemperatureEditFieldLabel   matlab.ui.control.Label
        RespiratoryrateEditField        matlab.ui.control.NumericEditField
        RespiratoryrateEditFieldLabel   matlab.ui.control.Label
        HeartrateEditField              matlab.ui.control.NumericEditField
        HeartrateEditFieldLabel         matlab.ui.control.Label
        BodyweightEditField             matlab.ui.control.NumericEditField
        BodyweightEditFieldLabel        matlab.ui.control.Label
        BodyheightEditField             matlab.ui.control.NumericEditField
        BodyheightEditFieldLabel        matlab.ui.control.Label
        SystolicbloodpressureEditField  matlab.ui.control.NumericEditField
        SystolicbloodpressureEditFieldLabel  matlab.ui.control.Label
        DiastolicbloodpressureEditField  matlab.ui.control.NumericEditField
        DiastolicbloodpressureEditFieldLabel  matlab.ui.control.Label
        MedicationsTab                  matlab.ui.container.Tab
        MedicationAllergiesEditField    matlab.ui.control.EditField
        MedicationAllergiesEditFieldLabel  matlab.ui.control.Label
        MedicationsEditField            matlab.ui.control.EditField
        MedicationsEditFieldLabel       matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Data.Sex = app.SexDropDown.Value;
            Data.name = app.NameEditField.Value;
            save('resultados.mat','Data');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            fig = uifigure('HandleVisibility', 'on');%('Position',[100 100 425 275]);
            msg = 'Would you like to save the data?';
            title = 'Confirm Exit';
            selection = uiconfirm(fig,msg,title,...
                'Options',{'Yes','No'},'Icon','warning');
            switch  selection
                case 'Yes'
                    SaveButtonPushed(app, event)
                case 'No'
                    % do nothing
            end
        close(fig);
        close(app.UIFigure);
        end

        % Button pushed function: LoadCTimageButton
        function LoadCTimageButtonPushed(app, event)
            global Image;
            [filename,filepath]=uigetfile({'*.png';'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file to open');
            fullpathname = strcat(filepath,filename);
              Image = imread(fullpathname);
              imshow(Image,'parent', app.UIAxes);
        end

        % Button pushed function: ClassifyImageButton
        function ClassifyImageButtonPushed(app, event)
            load('TrainedNetwork.mat');
            global Image;

            % Resizing image to 224-by-224-by-3 pixels to make them
            % compatible with the input size of the network.
            inputSize = net.Layers(1).InputSize; 
            img = imresize(Image,inputSize(1:2)); 

            [classfn,probs] = classify(net,img); % Classifying image using last trained network 

            % Grad-CAM reveals the Resnet decisions
            scoreMap = gradCAM(net,img,classfn);

            imshow(img,'parent', app.UIAxes);

            figure,
            imshow(img,scoreMap);
            hold on, imshow(scoreMap);

            % DEBE HABER UNA MEJOR FORMA PARA QUITAR LOS EJES
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);

            hold(app.UIAxes,'on')
            imagesc(scoreMap,'AlphaData',0.5);
            colormap jet
            hold off;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatientdataTab = uitab(app.TabGroup);
            app.PatientdataTab.Title = 'Patient data';
            app.NameEditFieldLabel = uilabel(app.PatientdataTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PatientdataTab, 'text');
            app.SexDropDownLabel = uilabel(app.PatientdataTab);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.PatientdataTab);
            app.SexDropDown.Items = {'Male', 'Female', 'Non-binary', 'Transgender', 'Prefer not to say'};
            app.SexDropDown.Value = 'Male';
            app.DateofBirthDatePickerLabel = uilabel(app.PatientdataTab);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirthDatePicker = uidatepicker(app.PatientdataTab);
            app.RaceandEthnicityDropDownLabel = uilabel(app.PatientdataTab);
            app.RaceandEthnicityDropDownLabel.Text = 'Race and Ethnicity';
            app.RaceandEthnicityDropDown = uidropdown(app.PatientdataTab);
            app.RaceandEthnicityDropDown.Items = {'White', 'Hispanic or Latino', 'Black or African American', 'American Indian or Alaska Native', 'Asian', 'Native Hawaiian or Other Pacific Islander'};
            app.RaceandEthnicityDropDown.Value = 'White';
            app.EmergencycontactphonenumberEditFieldLabel = uilabel(app.PatientdataTab);
            app.EmergencycontactphonenumberEditFieldLabel.Text = 'Emergency contact phone number';
            app.EmergencycontactphonenumberEditField = uieditfield(app.PatientdataTab, 'numeric');
            app.EmergencycontactphonenumberEditField.ValueDisplayFormat = '%.0f';
            app.EmergencycontactnameEditFieldLabel = uilabel(app.PatientdataTab);
            app.EmergencycontactnameEditFieldLabel.Text = 'Emergency contact name';
            app.EmergencycontactnameEditField = uieditfield(app.PatientdataTab, 'text');
            app.PhonenumberEditField_2Label = uilabel(app.PatientdataTab);
            app.PhonenumberEditField_2Label.Text = 'Phone number';
            app.PhonenumberEditField_2 = uieditfield(app.PatientdataTab, 'numeric');
            app.SmokingStatusDropDownLabel = uilabel(app.PatientdataTab);
            app.SmokingStatusDropDownLabel.Text = 'Smoking Status';
            app.SmokingStatusDropDown = uidropdown(app.PatientdataTab);
            app.SmokingStatusDropDown.Items = {'Current every day smoker', 'Current some day smoker', 'Former smoker', 'Never smoker'};
            app.SmokingStatusDropDown.Value = 'Current every day smoker';
            app.VitalSignsTab = uitab(app.TabGroup);
            app.VitalSignsTab.Title = 'Vital Signs';
            app.DiastolicbloodpressureEditFieldLabel = uilabel(app.VitalSignsTab);
            app.DiastolicbloodpressureEditFieldLabel.Text = 'Diastolic blood pressure';
            app.DiastolicbloodpressureEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.SystolicbloodpressureEditFieldLabel = uilabel(app.VitalSignsTab);
            app.SystolicbloodpressureEditFieldLabel.Text = 'Systolic blood pressure';
            app.SystolicbloodpressureEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.BodyheightEditFieldLabel = uilabel(app.VitalSignsTab);
            app.BodyheightEditFieldLabel.Text = 'Body height';
            app.BodyheightEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.BodyweightEditFieldLabel = uilabel(app.VitalSignsTab);
            app.BodyweightEditFieldLabel.Text = 'Body weight';
            app.BodyweightEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.HeartrateEditFieldLabel = uilabel(app.VitalSignsTab);
            app.HeartrateEditFieldLabel.Text = 'Heart rate';
            app.HeartrateEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.RespiratoryrateEditFieldLabel = uilabel(app.VitalSignsTab);
            app.RespiratoryrateEditFieldLabel.Text = 'Respiratory rate';
            app.RespiratoryrateEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.BodytemperatureEditFieldLabel = uilabel(app.VitalSignsTab);
            app.BodytemperatureEditFieldLabel.Text = 'Body temperature';
            app.BodytemperatureEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.PulseoximetryEditFieldLabel = uilabel(app.VitalSignsTab);
            app.PulseoximetryEditFieldLabel.Text = 'Pulse oximetry';
            app.PulseoximetryEditField = uieditfield(app.VitalSignsTab, 'numeric');
            app.MedicationsTab = uitab(app.TabGroup);
            app.MedicationsTab.Title = 'Medications';
            app.MedicationsEditFieldLabel = uilabel(app.MedicationsTab);
            app.MedicationsEditFieldLabel.Text = 'Medications';
            app.MedicationsEditField = uieditfield(app.MedicationsTab, 'text');
            app.MedicationAllergiesEditFieldLabel = uilabel(app.MedicationsTab);
            app.MedicationAllergiesEditFieldLabel.Text = {'Medication'; 'Allergies'};
            app.MedicationAllergiesEditField = uieditfield(app.MedicationsTab, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.LoadCTimageButton = uibutton(app.UIFigure, 'push');
            app.LoadCTimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCTimageButtonPushed, true);
            app.LoadCTimageButton.Text = 'Load CT image';
            app.ClassifyImageButton = uibutton(app.UIFigure, 'push');
            app.ClassifyImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyImageButtonPushed, true);
            app.ClassifyImageButton.Text = 'Classify Image';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'CT image', 'Grad-CAM'};
            app.Switch.Value = 'CT image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end